classdef writing_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        HelpMenu    matlab.ui.container.Menu
        DoneButton  matlab.ui.control.Button
        Space       matlab.ui.control.EditField
        WritedownwhatyouwanttherobottowriteontheboardEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.DoneButton.Enable = false;
            app.Space.Enable = false;
            str = app.Space.Value;
            alph = isletter(str);
            space = isspace(str);
            a = alph(:) == space(:);
            if any(a == true)
                warndlg("The input must not contain special character.")
                app.DoneButton.Enable = true;
                app.Space.Enable = true;
                return
            end
            str2 = upper(str)
            try
                IP = "192.168.0.20";
                port = 1025;
                tcp = tcpclient(IP, port);
                str = "Connected";
                tcp.write(str);
                tcp.flush;
                flag = 0;
                count = 0;
                while flag == 0
                    str = read(tcp);
                    if char(str) == "RSConnected"
                        break
                    end
                    count = count+1;
                    if count > 30
                        ME = MException("connectionError", "Failed to get a response from the host");
                        clear tcp
                        throw(ME);
                    end
                    pause(0.5);
                end
                tcp.write("5");
                flag = 0;
                count = 0;
                while flag == 0
                    str = read(tcp);
                    if char(str) == "InFive"
                        break
                    end
                    count = count+1;
                    if count > 30
                        ME = MException("connectionError", "Failed to get a response from the host");
                        clear tcp
                        throw(ME);
                    end
                    pause(0.5);
                end
                flag = 0;
                while flag == 0
                    str = read(tcp);
                    if char(str) == "WriteFinished"
                        break
                    end
                    pause(0.5);
                end

            catch ME
                if ME.identifier == "connectionError"
                    warndlg("Cannot receive signal from server");
                else
                    warndlg("Cannot connect to server");
                end
            end
            tcp.write(str2);
            clear tcp
            app.DoneButton.Enable = true;
            app.Space.Enable = true;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.WritedownwhatyouwanttherobottowriteontheboardEditFieldLabel = uilabel(app.UIFigure);
            app.WritedownwhatyouwanttherobottowriteontheboardEditFieldLabel.Text = 'Write down what you want the robot to write on the board';
            app.Space = uieditfield(app.UIFigure, 'text');
            app.Space.Value = 'Sample';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = writing_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end